classdef Viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Var_Tree                       matlab.ui.container.Tree
        IterationNumberEditFieldLabel  matlab.ui.control.Label
        IterationNumberEditField       matlab.ui.control.NumericEditField
        Var_Value                      matlab.ui.control.Table
        ModelinLoopSimulationViewerLabel  matlab.ui.control.Label
        OutputVariablesLabel           matlab.ui.control.Label
        VariableValueLabel             matlab.ui.control.Label
        SimulationPathEditFieldLabel   matlab.ui.control.Label
        SimulationPathEditField        matlab.ui.control.EditField
        Image                          matlab.ui.control.Image
        Integervalue1NLabel            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Delete previous Var_Tree nodes
            a = app.Var_Tree.Children;
            a.delete;    
            
            % Load simulation path 
            sim_path = app.SimulationPathEditField.Value;
            
            if isempty(sim_path) == 0
                
                % Generate file path
                output_path = sim_path + "\Output\";                
                file_path = output_path + "SIS_iter_" + string(app.IterationNumberEditField.Value) + ".mat";
                
                % Update Integervalue1NLabel
                log_file_path = output_path + "SIS_log.mat";
                log_data = load(log_file_path);
                N_iter = log_data.SIS_log.N_Iter;
                app.Integervalue1NLabel.Text = 'Integer value: [1, ' + string(N_iter) + ']';
            
                % Load variables from .mat file
                myStruct = load(file_path);
                
                % Load names of structres in .mat file
                fNames1 = fieldnames(myStruct);
                
                % Create tree nodes
                for i=1:numel(fieldnames(myStruct))        
                    
                    % Create parent nodes
                    category(i)=uitreenode(app.Var_Tree,'Text',string(fNames1(i)));
                    
                    % Load names of variables within structures
                    fNames2 = fieldnames(myStruct.(string(fNames1(i))));
                    
                    % Create lower-level nodes
                    for n=1:numel(fieldnames(myStruct.(string(fNames1(i)))))
                        
                        % Create children nodes
                        tmp_data = myStruct.(string(fNames1(i))).(string(fNames2(n)));
                        uitreenode(category(i),'Text',string(fNames2(n)), "NodeData", tmp_data);
                    end
                end 
                
                % Expand the tree
                expand(app.Var_Tree,'all')
            end
        end

        % Selection changed function: Var_Tree
        function Var_TreeSelectionChanged(app, event)
            selectedNodes = app.Var_Tree.SelectedNodes;
            
            
            tdata = table(selectedNodes.NodeData);
            
            % Update Var_Table
            app.Var_Value.ColumnName = {selectedNodes.Text};
            app.Var_Value.RowName = 'numbered';
            app.Var_Value.Position = [211 17 450 390];
            app.Var_Value.Data = tdata;
            
        end

        % Value changed function: IterationNumberEditField
        function IterationNumberEditFieldValueChanged(app, event)
            value = app.IterationNumberEditField.Value;

            startupFcn(app); % Reload app
        end

        % Value changed function: SimulationPathEditField
        function SimulationPathEditFieldValueChanged(app, event)
            value = app.SimulationPathEditField.Value;
            
            startupFcn(app); % Reload app
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Var_Tree = uitree(app.UIFigure);
            app.Var_Tree.SelectionChangedFcn = createCallbackFcn(app, @Var_TreeSelectionChanged, true);
            app.IterationNumberEditFieldLabel = uilabel(app.UIFigure);
            app.IterationNumberEditFieldLabel.Text = 'Iteration Number';
            app.IterationNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationNumberEditField.ValueChangedFcn = createCallbackFcn(app, @IterationNumberEditFieldValueChanged, true);
            app.IterationNumberEditField.Value = 1;
            app.Var_Value = uitable(app.UIFigure);
            app.Var_Value.ColumnName = {''};
            app.Var_Value.RowName = {};
            app.ModelinLoopSimulationViewerLabel = uilabel(app.UIFigure);
            app.ModelinLoopSimulationViewerLabel.Text = 'Model-in-Loop Simulation | Viewer';
            app.OutputVariablesLabel = uilabel(app.UIFigure);
            app.OutputVariablesLabel.Text = 'Output - Variables';
            app.VariableValueLabel = uilabel(app.UIFigure);
            app.VariableValueLabel.Text = 'Variable - Value';
            app.SimulationPathEditFieldLabel = uilabel(app.UIFigure);
            app.SimulationPathEditFieldLabel.Text = 'Simulation Path';
            app.SimulationPathEditField = uieditfield(app.UIFigure, 'text');
            app.SimulationPathEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationPathEditFieldValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'IITB-SSP_Logo.jpg';
            app.Integervalue1NLabel = uilabel(app.UIFigure);
            app.Integervalue1NLabel.Text = 'Integer value: [1, N]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end